classdef Ali_week5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CaptureImageButton              matlab.ui.control.Button
        CameraLabel                     matlab.ui.control.Label
        Right_Rear_ControlSlider_2      matlab.ui.control.Slider
        Right_Rear_ControlSlider_2Label  matlab.ui.control.Label
        Right_Front_ControlSlider_2     matlab.ui.control.Slider
        Right_Front_ControlSlider_2Label  matlab.ui.control.Label
        Left_Front_ControlSlider_2      matlab.ui.control.Slider
        Left_Front_ControlSlider_2Label  matlab.ui.control.Label
        Left_Rear_ControlSlider_2       matlab.ui.control.Slider
        Left_Rear_ControlSlider_2Label  matlab.ui.control.Label
        Kuka_youBotwheelSlippingJointsLabel  matlab.ui.control.Label
        Right_Rear_ControlSlider        matlab.ui.control.Slider
        Right_Rear_ControlLabel         matlab.ui.control.Label
        Left_Rear_ControlSlider         matlab.ui.control.Slider
        Left_Rear_ControlSliderLabel    matlab.ui.control.Label
        Left_Front_ControlSlider        matlab.ui.control.Slider
        Left_Front_ControlSliderLabel   matlab.ui.control.Label
        Right_Front_ControlSlider       matlab.ui.control.Slider
        Right_Front_ControlSliderLabel  matlab.ui.control.Label
        Kuka_youBotwheelRollingJointsLabel  matlab.ui.control.Label
        Kuka_youBotGripperJointsLabel   matlab.ui.control.Label
        Robot_GripJoint2Slider          matlab.ui.control.Slider
        Robot_GripJoint2SliderLabel     matlab.ui.control.Label
        Robot_GripJoint1Slider          matlab.ui.control.Slider
        Robot_GripJoint1SliderLabel     matlab.ui.control.Label
        Joint2Slider                    matlab.ui.control.Slider
        Joint2SliderLabel               matlab.ui.control.Label
        Joint3Slider                    matlab.ui.control.Slider
        Joint3SliderLabel               matlab.ui.control.Label
        Joint4Slider                    matlab.ui.control.Slider
        Joint4SliderLabel               matlab.ui.control.Label
        Joint1Slider                    matlab.ui.control.Slider
        Joint1SliderLabel               matlab.ui.control.Label
        Kuka_youBotArmJointsLabel       matlab.ui.control.Label
        Joint0Slider                    matlab.ui.control.Slider
        Joint0SliderLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        vrep
        returnCode
        clientID
        
        %rolling joints
       RollingFrontLeft
       RollingFrontRight
       RollingRearRight
       RollingRearLeft
        
        %slipping joints
       SlippingFrontLeft
       SlippingFrontRight
       SlippingRearRight
       SlippingRearLeft
      
       % youbot Arm Joints
       armJoint0
       armJoint1
       armJoint2
       armJoint3
       armJoint4
       
       %  youbotGripper Joints
      armGripJoint1
      armGripJoint2
      
      % camera
      visionCamera
      imagecapture
      resolution
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    app.vrep=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
    app.vrep.simxFinish(-1); % just in case, close all opened connections
    app.clientID=app.vrep.simxStart('127.0.0.1',19999,true,true,5000,5);
    if (app.clientID>-1)
        disp('Connected to remote API server');
       
       
        % get object handlers for the rolling base joints
        
       [app.returnCode,app.RollingFrontLeft]=app.vrep.simxGetObjectHandle(app.clientID,'rollingJoint_fl',app.vrep.simx_opmode_blocking);
       [app.returnCode,app.RollingFrontRight]=app.vrep.simxGetObjectHandle(app.clientID,'rollingJoint_fr',app.vrep.simx_opmode_blocking);
       [app.returnCode,app.RollingRearRight]=app.vrep.simxGetObjectHandle(app.clientID,'rollingJoint_rr',app.vrep.simx_opmode_blocking);
       [app.returnCode,app.RollingRearLeft]=app.vrep.simxGetObjectHandle(app.clientID,'rollingJoint_rl',app.vrep.simx_opmode_blocking);
       
       % object handles for slipping base joints
       [app.returnCode,app.SlippingFrontLeft]=app.vrep.simxGetObjectHandle(app.clientID,'slippingJoint_fl',app.vrep.simx_opmode_blocking);
       [app.returnCode,app.SlippingFrontRight]=app.vrep.simxGetObjectHandle(app.clientID,'slippingJoint_fr',app.vrep.simx_opmode_blocking);
       [app.returnCode,app.SlippingRearRight]=app.vrep.simxGetObjectHandle(app.clientID,'slippingJoint_rr',app.vrep.simx_opmode_blocking);
       [app.returnCode,app.SlippingRearLeft]=app.vrep.simxGetObjectHandle(app.clientID,'slippingJoint_rl',app.vrep.simx_opmode_blocking);
       
       % object handles for youBotArm Joints
       [app.returnCode,app.armJoint0]=app.vrep.simxGetObjectHandle(app.clientID,'youBotArmJoint0',app.vrep.simx_opmode_blocking);
       [app.returnCode,app.armJoint1]=app.vrep.simxGetObjectHandle(app.clientID,'youBotArmJoint1',app.vrep.simx_opmode_blocking);
       [app.returnCode,app.armJoint2]=app.vrep.simxGetObjectHandle(app.clientID,'youBotArmJoint2',app.vrep.simx_opmode_blocking);
       [app.returnCode,app.armJoint3]=app.vrep.simxGetObjectHandle(app.clientID,'youBotArmJoint3',app.vrep.simx_opmode_blocking);
       [app.returnCode,app.armJoint4]=app.vrep.simxGetObjectHandle(app.clientID,'youBotArmJoint4',app.vrep.simx_opmode_blocking);
       
       % object handles for youBot Grippers
       [app.returnCode,app.armGripJoint1]=app.vrep.simxGetObjectHandle(app.clientID,'youBotGripperJoint1',app.vrep.simx_opmode_blocking);
       [app.returnCode,app.armGripJoint2]=app.vrep.simxGetObjectHandle(app.clientID,'youBotGripperJoint2',app.vrep.simx_opmode_blocking);
       
       %object handles for vision sensor
       [app.returnCode,app.visionCamera]=app.vrep.simxGetObjectHandle(app.clientID,'Vision_sensor',app.vrep.simx_opmode_blocking);
        
      else
        disp('Failed connecting to remote API server');
    end
    app.vrep.delete(); % call the destructor!
        end

        % Value changing function: Right_Rear_ControlSlider
        function Right_Rear_ControlSliderValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetVelocity(app.clientID,app.RollingRearRight,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Left_Rear_ControlSlider
        function Left_Rear_ControlSliderValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetVelocity(app.clientID,app.RollingRearLeft,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Left_Front_ControlSlider
        function Left_Front_ControlSliderValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetVelocity(app.clientID,app.RollingFrontLeft,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Right_Front_ControlSlider
        function Right_Front_ControlSliderValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetVelocity(app.clientID,app.RollingFrontRight,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Right_Rear_ControlSlider_2
        function Right_Rear_ControlSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetVelocity(app.clientID,app.SlippingRearRight,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Right_Front_ControlSlider_2
        function Right_Front_ControlSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetVelocity(app.clientID,app.SlippingFrontRight,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Left_Front_ControlSlider_2
        function Left_Front_ControlSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetVelocity(app.clientID,app.SlippingFrontLeft,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Left_Rear_ControlSlider_2
        function Left_Rear_ControlSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetVelocity(app.clientID,app.SlippingRearLeft,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Robot_GripJoint2Slider
        function Robot_GripJoint2SliderValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetPosition(app.clientID,app.armGripJoint2,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Robot_GripJoint1Slider
        function Robot_GripJoint1SliderValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetPosition(app.clientID,app.armGripJoint1,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Joint0Slider
        function Joint0SliderValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetPosition(app.clientID,app.armJoint0,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetPosition(app.clientID,app.armJoint1,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetPosition(app.clientID,app.armJoint4,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetPosition(app.clientID,app.armJoint3,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            [app.returnCode]=app.vrep.simxSetJointTargetPosition(app.clientID,app.armJoint2,changingValue,app.vrep.simx_opmode_blocking);
        end

        % Button pushed function: CaptureImageButton
        function CaptureImageButtonPushed(app, event)
            
        [app.returnCode,app.resolution,app.imagecapture]=app.vrep.simxGetVisionSensorImage2(app.clientID,app.visionCamera,0,app.vrep.simx_opmode_streaming);
        for i=50
       
        [app.returnCode,app.resolution, app.imagecapture]=app.vrep.simxGetVisionSensorImage2(app.clientID,app.visionCamera,0,app.vrep.simx_opmode_buffer);
        imshow(app.imagecapture)
       
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Joint0SliderLabel = uilabel(app.UIFigure);
            app.Joint0SliderLabel.Text = {'Joint0'; ''};
            app.Joint0Slider = uislider(app.UIFigure);
            app.Joint0Slider.ValueChangingFcn = createCallbackFcn(app, @Joint0SliderValueChanging, true);
            app.Kuka_youBotArmJointsLabel = uilabel(app.UIFigure);
            app.Kuka_youBotArmJointsLabel.Text = {'Kuka_'; 'youBot ArmJoints'};
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = {'Joint1'; ''};
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Robot_GripJoint1SliderLabel = uilabel(app.UIFigure);
            app.Robot_GripJoint1SliderLabel.Text = {'Robot_'; 'GripJoint1'};
            app.Robot_GripJoint1Slider = uislider(app.UIFigure);
            app.Robot_GripJoint1Slider.ValueChangingFcn = createCallbackFcn(app, @Robot_GripJoint1SliderValueChanging, true);
            app.Robot_GripJoint2SliderLabel = uilabel(app.UIFigure);
            app.Robot_GripJoint2SliderLabel.Text = {'Robot_'; 'GripJoint2'};
            app.Robot_GripJoint2Slider = uislider(app.UIFigure);
            app.Robot_GripJoint2Slider.ValueChangingFcn = createCallbackFcn(app, @Robot_GripJoint2SliderValueChanging, true);
            app.Kuka_youBotGripperJointsLabel = uilabel(app.UIFigure);
            app.Kuka_youBotGripperJointsLabel.Text = 'Kuka_youBot GripperJoints';
            app.Kuka_youBotwheelRollingJointsLabel = uilabel(app.UIFigure);
            app.Kuka_youBotwheelRollingJointsLabel.Text = 'Kuka_youBot  wheel RollingJoints';
            app.Right_Front_ControlSliderLabel = uilabel(app.UIFigure);
            app.Right_Front_ControlSliderLabel.Text = {'Right_'; 'Front_Control'; ''};
            app.Right_Front_ControlSlider = uislider(app.UIFigure);
            app.Right_Front_ControlSlider.ValueChangingFcn = createCallbackFcn(app, @Right_Front_ControlSliderValueChanging, true);
            app.Left_Front_ControlSliderLabel = uilabel(app.UIFigure);
            app.Left_Front_ControlSliderLabel.Text = {'Left_'; 'Front_Control'};
            app.Left_Front_ControlSlider = uislider(app.UIFigure);
            app.Left_Front_ControlSlider.ValueChangingFcn = createCallbackFcn(app, @Left_Front_ControlSliderValueChanging, true);
            app.Left_Rear_ControlSliderLabel = uilabel(app.UIFigure);
            app.Left_Rear_ControlSliderLabel.Text = {'Left_'; 'Rear_Control'};
            app.Left_Rear_ControlSlider = uislider(app.UIFigure);
            app.Left_Rear_ControlSlider.ValueChangingFcn = createCallbackFcn(app, @Left_Rear_ControlSliderValueChanging, true);
            app.Right_Rear_ControlLabel = uilabel(app.UIFigure);
            app.Right_Rear_ControlLabel.Text = {'Right'; '_Rear_Control'; ''};
            app.Right_Rear_ControlSlider = uislider(app.UIFigure);
            app.Right_Rear_ControlSlider.ValueChangingFcn = createCallbackFcn(app, @Right_Rear_ControlSliderValueChanging, true);
            app.Kuka_youBotwheelSlippingJointsLabel = uilabel(app.UIFigure);
            app.Kuka_youBotwheelSlippingJointsLabel.Text = 'Kuka_youBot  wheel SlippingJoints';
            app.Left_Rear_ControlSlider_2Label = uilabel(app.UIFigure);
            app.Left_Rear_ControlSlider_2Label.Text = {'Left'; '_Rear_Control'; ''};
            app.Left_Rear_ControlSlider_2 = uislider(app.UIFigure);
            app.Left_Rear_ControlSlider_2.ValueChangingFcn = createCallbackFcn(app, @Left_Rear_ControlSlider_2ValueChanging, true);
            app.Left_Front_ControlSlider_2Label = uilabel(app.UIFigure);
            app.Left_Front_ControlSlider_2Label.Text = {'Left_'; 'Front_Control'};
            app.Left_Front_ControlSlider_2 = uislider(app.UIFigure);
            app.Left_Front_ControlSlider_2.ValueChangingFcn = createCallbackFcn(app, @Left_Front_ControlSlider_2ValueChanging, true);
            app.Right_Front_ControlSlider_2Label = uilabel(app.UIFigure);
            app.Right_Front_ControlSlider_2Label.Text = {'Right_'; 'Front_Control'};
            app.Right_Front_ControlSlider_2 = uislider(app.UIFigure);
            app.Right_Front_ControlSlider_2.ValueChangingFcn = createCallbackFcn(app, @Right_Front_ControlSlider_2ValueChanging, true);
            app.Right_Rear_ControlSlider_2Label = uilabel(app.UIFigure);
            app.Right_Rear_ControlSlider_2Label.Text = {'Right'; '_Rear_Control'; ''};
            app.Right_Rear_ControlSlider_2 = uislider(app.UIFigure);
            app.Right_Rear_ControlSlider_2.ValueChangingFcn = createCallbackFcn(app, @Right_Rear_ControlSlider_2ValueChanging, true);
            app.CameraLabel = uilabel(app.UIFigure);
            app.CameraLabel.Text = {'Camera '; ''};
            app.CaptureImageButton = uibutton(app.UIFigure, 'push');
            app.CaptureImageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureImageButtonPushed, true);
            app.CaptureImageButton.Text = {'Capture Image'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ali_week5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end